classdef PlotTwoSigsOptions_240517 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        OptionsUIFigure                matlab.ui.Figure
        MinMagEditField                matlab.ui.control.NumericEditField
        MinMagEditFieldLabel           matlab.ui.control.Label
        MaxMagEditField                matlab.ui.control.NumericEditField
        MaxMagEditFieldLabel           matlab.ui.control.Label
        MinHzEditField                 matlab.ui.control.NumericEditField
        MinHzEditFieldLabel            matlab.ui.control.Label
        MaxHzEditField                 matlab.ui.control.NumericEditField
        MaxHzEditFieldLabel            matlab.ui.control.Label
        PlotSpectrogramCheckBox_2      matlab.ui.control.CheckBox
        PlotSpectrogramCheckBox        matlab.ui.control.CheckBox
        Plot1Label                     matlab.ui.control.Label
        RawDirEditFieldLabel           matlab.ui.control.Label
        SpikeDirEditFieldLabel         matlab.ui.control.Label
        LowPassHzEditField_2Label      matlab.ui.control.Label
        Defaultfiltering3003000HzforspikesLabel  matlab.ui.control.Label
        HighPassHzEditField_2Label     matlab.ui.control.Label
        Plot2Label                     matlab.ui.control.Label
        RawDirEditField_2Label         matlab.ui.control.Label
        SpikeDirEditField_2Label       matlab.ui.control.Label
        Defaultfiltering3003000HzforspikesLabel_2  matlab.ui.control.Label
        StartsEditFieldLabel           matlab.ui.control.Label
        StopsEditFieldLabel            matlab.ui.control.Label
        SDThreshPlot1EditFieldLabel    matlab.ui.control.Label
        SpikeDynDirEditField_2Label    matlab.ui.control.Label
        FIDEditField_2Label            matlab.ui.control.Label
        PlotButton_4                   matlab.ui.control.Button
        FeedForwardYNDropDown_2        matlab.ui.control.DropDown
        RowEditField_2Label            matlab.ui.control.Label
        RowEditField_2                 matlab.ui.control.EditField
        ORLabel_2                      matlab.ui.control.Label
        ChannelNameEditField_2Label    matlab.ui.control.Label
        ChannelNameEditField_2         matlab.ui.control.EditField
        FIDEditField_2                 matlab.ui.control.EditField
        BurstBoundsLabel_2             matlab.ui.control.Label
        SpikeDynDirEditField_2         matlab.ui.control.EditField
        LoadSpikeDynamics2Label        matlab.ui.control.Label
        FeedForwardYNDropDown_2Label   matlab.ui.control.Label
        PlotButton_3                   matlab.ui.control.Button
        FeedForwardYNDropDown          matlab.ui.control.DropDown
        RowEditField                   matlab.ui.control.EditField
        ORLabel                        matlab.ui.control.Label
        ChannelNameEditField           matlab.ui.control.EditField
        FIDEditField                   matlab.ui.control.EditField
        BurstBoundsLabel               matlab.ui.control.Label
        SpikeDynDirEditField           matlab.ui.control.EditField
        LoadSpikeDynamics1Label        matlab.ui.control.Label
        FeedForwardYNDropDownLabel     matlab.ui.control.Label
        ChannelNameEditFieldLabel      matlab.ui.control.Label
        RowEditFieldLabel              matlab.ui.control.Label
        FIDEditFieldLabel              matlab.ui.control.Label
        SpikeDynDirEditFieldLabel      matlab.ui.control.Label
        PlotThreshButton_2             matlab.ui.control.Button
        SDThreshPlot2EditField         matlab.ui.control.NumericEditField
        PlotThreshButton               matlab.ui.control.Button
        SDThreshPlot1EditField         matlab.ui.control.NumericEditField
        SDThreshPlot2EditFieldLabel    matlab.ui.control.Label
        HighGamma100300HzCheckBox_2    matlab.ui.control.CheckBox
        LowGamma30100HzCheckBox_2      matlab.ui.control.CheckBox
        Spindle1016HzCheckBox_2        matlab.ui.control.CheckBox
        Theta410HzCheckBox_2           matlab.ui.control.CheckBox
        Delta054HzCheckBox_2           matlab.ui.control.CheckBox
        EnvelopeCheckBox_2             matlab.ui.control.CheckBox
        PhaseCheckBox_2                matlab.ui.control.CheckBox
        Overlay2Label                  matlab.ui.control.Label
        HighGamma100300HzCheckBox      matlab.ui.control.CheckBox
        LowGamma30100HzCheckBox        matlab.ui.control.CheckBox
        Spindle1016HzCheckBox          matlab.ui.control.CheckBox
        Theta410HzCheckBox             matlab.ui.control.CheckBox
        Delta054HzCheckBox             matlab.ui.control.CheckBox
        EnvelopeCheckBox               matlab.ui.control.CheckBox
        PhaseCheckBox                  matlab.ui.control.CheckBox
        Overlay1Label                  matlab.ui.control.Label
        SamplingratekHzEditField       matlab.ui.control.NumericEditField
        StopsEditField                 matlab.ui.control.NumericEditField
        StartsEditField                matlab.ui.control.NumericEditField
        SharedOptionsLabel             matlab.ui.control.Label
        SamplingratekHzEditFieldLabel  matlab.ui.control.Label
        PlotButton_2                   matlab.ui.control.Button
        HighPassHzEditField_2          matlab.ui.control.EditField
        LowPassHzEditField_2           matlab.ui.control.EditField
        SpikeDirEditField_2            matlab.ui.control.EditField
        RawDirEditField_2              matlab.ui.control.EditField
        PlotButton                     matlab.ui.control.Button
        HighPassHzEditField            matlab.ui.control.EditField
        LowPassHzEditField             matlab.ui.control.EditField
        SpikeDirEditField              matlab.ui.control.EditField
        RawDirEditField                matlab.ui.control.EditField
        HighPassHzEditFieldLabel       matlab.ui.control.Label
        LowPassHzEditFieldLabel        matlab.ui.control.Label
    end


    properties (Access = private)
        MainApp % Description
        isRawPlot1=0;
        isRawPlot2=0;
        BurstApp_1
        BurstApp_2
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.MainApp=mainapp;
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            if ~isempty(app.RawDirEditField.Value)
                plot_dir(app.MainApp, 1, app.RawDirEditField.Value, ...
                    double(string(app.LowPassHzEditField.Value)), double(string(app.HighPassHzEditField.Value)), ...
                    double(string(app.SamplingratekHzEditField.Value)), double(string(app.StartsEditField.Value)), ...
                    double(string(app.StopsEditField.Value)),app.PlotSpectrogramCheckBox.Value, ...
                    app.MaxHzEditField.Value, app.MinHzEditField.Value, app.MaxMagEditField.Value, app.MinMagEditField.Value)
            end
            if ~isempty(app.SpikeDirEditField.Value)
                plot_spikes(app.MainApp, 1, app.SpikeDirEditField.Value, app.StartsEditField.Value, app.StopsEditField.Value,~isempty(app.RawDirEditField.Value))
            end
        end

        % Close request function: OptionsUIFigure
        function OptionsUIFigureCloseRequest(app, event)

            if isvalid(app.MainApp)
                app.MainApp.OptionsButton.Enable='on';
            end

            delete(app)

        end

        % Button pushed function: PlotButton_2
        function PlotButton_2Pushed(app, event)
            if ~isempty(app.RawDirEditField_2.Value)
                plot_dir(app.MainApp, 2, app.RawDirEditField_2.Value, ...
                    double(string(app.LowPassHzEditField_2.Value)), double(string(app.HighPassHzEditField_2.Value)), ...
                    double(string(app.SamplingratekHzEditField.Value)), double(string(app.StartsEditField.Value)), ...
                    double(string(app.StopsEditField.Value)),app.PlotSpectrogramCheckBox_2.Value)
            end
            if ~isempty(app.SpikeDirEditField_2.Value)
                plot_spikes(app.MainApp, 2, app.SpikeDirEditField_2.Value, app.StartsEditField.Value, app.StopsEditField.Value,~isempty(app.RawDirEditField_2.Value))
            end
        end

        % Value changed function: PhaseCheckBox
        function PhaseCheckBoxValueChanged(app, event)
            value = double(app.PhaseCheckBox.Value);
            plotPhase(app.MainApp,value,app.MainApp.UIAxes,app.RawDirEditField.Value,...
                double(string(app.SamplingratekHzEditField.Value)),...
                double(string(app.LowPassHzEditField.Value)), double(string(app.HighPassHzEditField.Value)))
        end

        % Value changed function: EnvelopeCheckBox
        function EnvelopeCheckBoxValueChanged(app, event)
            value = app.EnvelopeCheckBox.Value;
            plotEnvelope(app.MainApp,value,app.MainApp.UIAxes,app.RawDirEditField.Value,...
                double(string(app.SamplingratekHzEditField.Value)),...
                double(string(app.LowPassHzEditField.Value)), double(string(app.HighPassHzEditField.Value)))
        end

        % Value changed function: EnvelopeCheckBox_2
        function EnvelopeCheckBox_2ValueChanged(app, event)
            value = app.EnvelopeCheckBox_2.Value;
            plotEnvelope(app.MainApp,value,app.MainApp.UIAxes2,app.RawDirEditField_2.Value,...
                double(string(app.SamplingratekHzEditField.Value)),...
                double(string(app.LowPassHzEditField_2.Value)), double(string(app.HighPassHzEditField_2.Value)))
        end

        % Callback function
        function BurstBoundsButtonPushed(app, event)
            % app.BurstApp_1=
            BurstBoundsPlotting_240828(app,1);
            app.BurstBoundsButton.Enable="off";
        end

        % Button pushed function: PlotButton_3
        function PlotButton_3Pushed(app, event)
            myAxis=1;
            myDir=string(app.SpikeDynDirEditField.Value);
            myFS=double(app.SamplingratekHzEditField.Value);
            if ~isempty(app.RowEditField.Value)
                myRow=double(string(app.RowEditField.Value));
            elseif ~isempty(app.FIDEditField) & ~isempty(app.ChannelNameEditField)
                myDyn=importdata(erase(myDir,{'"',char("'")}));
                if app.FeedForwardYNDropDown.Value=="N/A"
                    myRow=find(myDyn.fi==str2double(app.FIDEditField.Value) & myDyn.channel_name==app.ChannelNameEditField.Value);
                else
                    myRow=find(myDyn.fi==str2double(app.FIDEditField.Value) & myDyn.channel_name==app.ChannelNameEditField.Value & myDyn.is_ff==double(app.FeedForwardYNDropDown.Value));
                end
            end
            if length(myRow)>1
                error("Input parameters do not specify a singular row.")
            end
            plotBurstBounds(app.MainApp,myAxis,myDir,myFS,myRow)
        end

        % Button pushed function: PlotButton_4
        function PlotButton_4Pushed(app, event)
            myAxis=2;
            myDir=string(app.SpikeDynDirEditField_2.Value);
            myFS=double(app.SamplingratekHzEditField.Value);
            if ~isempty(app.RowEditField_2.Value)
                myRow=double(string(app.RowEditField_2.Value));
            elseif ~isempty(app.FIDEditField_2) & ~isempty(app.ChannelNameEditField_2)
                myDyn=importdata(erase(myDir,{'"'}));
                if app.FeedForwardYNDropDown_2.Value=="N/A"
                    myRow=find(myDyn.fi==str2double(app.FIDEditField_2.Value) & myDyn.channel_name==app.ChannelNameEditField_2.Value);
                else
                    myRow=find(myDyn.fi==str2double(app.FIDEditField_2.Value) & myDyn.channel_name==app.ChannelNameEditField_2.Value & myDyn.is_ff==double(app.FeedForwardYNDropDown_2.Value));
                end
            end
            if length(myRow)>1
                error("Input parameters do not specify a singular row.")
            end
            plotBurstBounds(app.MainApp,myAxis,myDir,myFS,myRow)
        end

        % Button pushed function: PlotThreshButton
        function PlotThreshButtonPushed(app, event)
            myAxis=1;
            plotThresh(app.MainApp,double(app.SDThreshPlot1EditField.Value),myAxis)
        end

        % Button pushed function: PlotThreshButton_2
        function PlotThreshButton_2Pushed(app, event)
            myAxis=2;
            plotThresh(app.MainApp,double(app.SDThreshPlot2EditField.Value),myAxis)
        end

        % Value changed function: Theta410HzCheckBox
        function Theta410HzCheckBoxValueChanged(app, event)
            value = app.Theta410HzCheckBox.Value;
            myAxis=app.MainApp.UIAxes;
            plotThetaOverlay(app.MainApp,myAxis,app.RawDirEditField.Value,app.SamplingratekHzEditField.Value,value)
        end

        % Value changed function: Theta410HzCheckBox_2
        function Theta410HzCheckBox_2ValueChanged(app, event)
            value = app.Theta410HzCheckBox_2.Value;
            myAxis=app.MainApp.UIAxes2;
            plotThetaOverlay(app.MainApp,myAxis,app.RawDirEditField_2.Value,app.SamplingratekHzEditField.Value,value)
        end

        % Value changed function: Delta054HzCheckBox_2
        function Delta054HzCheckBox_2ValueChanged(app, event)
            value = app.Delta054HzCheckBox_2.Value;
            myAxis=app.MainApp.UIAxes2;
            plotDeltaOverlay(app.MainApp,myAxis,app.RawDirEditField_2.Value,app.SamplingratekHzEditField.Value,value)
        end

        % Value changed function: Delta054HzCheckBox
        function Delta054HzCheckBoxValueChanged(app, event)
            value = app.Delta054HzCheckBox.Value;
            myAxis=app.MainApp.UIAxes;
            plotDeltaOverlay(app.MainApp,myAxis,app.RawDirEditField.Value,app.SamplingratekHzEditField.Value,value)
        end

        % Value changed function: Spindle1016HzCheckBox
        function Spindle1016HzCheckBoxValueChanged(app, event)
            value = app.Spindle1016HzCheckBox.Value;
            myAxis=app.MainApp.UIAxes;
            plotSpindleOverlay(app.MainApp,myAxis,app.RawDirEditField.Value,app.SamplingratekHzEditField.Value,value)
        end

        % Value changed function: Spindle1016HzCheckBox_2
        function Spindle1016HzCheckBox_2ValueChanged(app, event)
            value = app.Spindle1016HzCheckBox_2.Value;
            myAxis=app.MainApp.UIAxes2;
            plotSpindleOverlay(app.MainApp,myAxis,app.RawDirEditField_2.Value,app.SamplingratekHzEditField.Value,value)
        end

        % Value changed function: LowGamma30100HzCheckBox
        function LowGamma30100HzCheckBoxValueChanged(app, event)
            value = app.LowGamma30100HzCheckBox.Value;
            myAxis=app.MainApp.UIAxes;
            plotLowGammaOverlay(app.MainApp,myAxis,app.RawDirEditField.Value,app.SamplingratekHzEditField.Value,value)
        end

        % Value changed function: LowGamma30100HzCheckBox_2
        function LowGamma30100HzCheckBox_2ValueChanged(app, event)
            value = app.LowGamma30100HzCheckBox_2.Value;
            myAxis=app.MainApp.UIAxes2;
            plotLowGammaOverlay(app.MainApp,myAxis,app.RawDirEditField_2.Value,app.SamplingratekHzEditField.Value,value)
        end

        % Value changed function: HighGamma100300HzCheckBox
        function HighGamma100300HzCheckBoxValueChanged(app, event)
            value = app.HighGamma100300HzCheckBox.Value;
            myAxis=app.MainApp.UIAxes;
            plotHighGammaOverlay(app.MainApp,myAxis,app.RawDirEditField.Value,app.SamplingratekHzEditField.Value,value)
        end

        % Value changed function: HighGamma100300HzCheckBox_2
        function HighGamma100300HzCheckBox_2ValueChanged(app, event)
            value = app.HighGamma100300HzCheckBox_2.Value;
            myAxis=app.MainApp.UIAxes2;
            plotHighGammaOverlay(app.MainApp,myAxis,app.RawDirEditField_2.Value,app.SamplingratekHzEditField.Value,value)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.OptionsUIFigure = uifigure('Visible', 'off');
            app.OptionsUIFigure.Name = 'Options';
            app.OptionsUIFigure.CloseRequestFcn = createCallbackFcn(app, @OptionsUIFigureCloseRequest, true);
            app.LowPassHzEditFieldLabel = uilabel(app.OptionsUIFigure);
            app.LowPassHzEditFieldLabel.Text = 'Low Pass (Hz)';
            app.HighPassHzEditFieldLabel = uilabel(app.OptionsUIFigure);
            app.HighPassHzEditFieldLabel.Text = 'High Pass (Hz)';
            app.RawDirEditField = uieditfield(app.OptionsUIFigure, 'text');
            app.SpikeDirEditField = uieditfield(app.OptionsUIFigure, 'text');
            app.LowPassHzEditField = uieditfield(app.OptionsUIFigure, 'text');
            app.LowPassHzEditField.Value = '3000';
            app.HighPassHzEditField = uieditfield(app.OptionsUIFigure, 'text');
            app.HighPassHzEditField.Value = '300';
            app.PlotButton = uibutton(app.OptionsUIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.RawDirEditField_2 = uieditfield(app.OptionsUIFigure, 'text');
            app.SpikeDirEditField_2 = uieditfield(app.OptionsUIFigure, 'text');
            app.LowPassHzEditField_2 = uieditfield(app.OptionsUIFigure, 'text');
            app.LowPassHzEditField_2.Value = '3000';
            app.HighPassHzEditField_2 = uieditfield(app.OptionsUIFigure, 'text');
            app.HighPassHzEditField_2.Value = '300';
            app.PlotButton_2 = uibutton(app.OptionsUIFigure, 'push');
            app.PlotButton_2.ButtonPushedFcn = createCallbackFcn(app, @PlotButton_2Pushed, true);
            app.PlotButton_2.Text = 'Plot';
            app.SamplingratekHzEditFieldLabel = uilabel(app.OptionsUIFigure);
            app.SamplingratekHzEditFieldLabel.Text = 'Sampling rate (kHz)';
            app.SharedOptionsLabel = uilabel(app.OptionsUIFigure);
            app.SharedOptionsLabel.Text = 'Shared Options';
            app.StartsEditField = uieditfield(app.OptionsUIFigure, 'numeric');
            app.StopsEditField = uieditfield(app.OptionsUIFigure, 'numeric');
            app.StopsEditField.Value = 300;
            app.SamplingratekHzEditField = uieditfield(app.OptionsUIFigure, 'numeric');
            app.SamplingratekHzEditField.Value = 25;
            app.Overlay1Label = uilabel(app.OptionsUIFigure);
            app.Overlay1Label.Text = 'Overlay 1';
            app.PhaseCheckBox = uicheckbox(app.OptionsUIFigure);
            app.PhaseCheckBox.ValueChangedFcn = createCallbackFcn(app, @PhaseCheckBoxValueChanged, true);
            app.PhaseCheckBox.Text = 'Phase';
            app.EnvelopeCheckBox = uicheckbox(app.OptionsUIFigure);
            app.EnvelopeCheckBox.ValueChangedFcn = createCallbackFcn(app, @EnvelopeCheckBoxValueChanged, true);
            app.EnvelopeCheckBox.Text = 'Envelope';
            app.Delta054HzCheckBox = uicheckbox(app.OptionsUIFigure);
            app.Delta054HzCheckBox.ValueChangedFcn = createCallbackFcn(app, @Delta054HzCheckBoxValueChanged, true);
            app.Delta054HzCheckBox.Text = 'Delta (0.5-4 Hz)';
            app.Theta410HzCheckBox = uicheckbox(app.OptionsUIFigure);
            app.Theta410HzCheckBox.ValueChangedFcn = createCallbackFcn(app, @Theta410HzCheckBoxValueChanged, true);
            app.Theta410HzCheckBox.Text = 'Theta (4-10 Hz)';
            app.Spindle1016HzCheckBox = uicheckbox(app.OptionsUIFigure);
            app.Spindle1016HzCheckBox.ValueChangedFcn = createCallbackFcn(app, @Spindle1016HzCheckBoxValueChanged, true);
            app.Spindle1016HzCheckBox.Text = 'Spindle (10-16 Hz)';
            app.LowGamma30100HzCheckBox = uicheckbox(app.OptionsUIFigure);
            app.LowGamma30100HzCheckBox.ValueChangedFcn = createCallbackFcn(app, @LowGamma30100HzCheckBoxValueChanged, true);
            app.LowGamma30100HzCheckBox.Text = 'Low Gamma (30-100 Hz)';
            app.HighGamma100300HzCheckBox = uicheckbox(app.OptionsUIFigure);
            app.HighGamma100300HzCheckBox.ValueChangedFcn = createCallbackFcn(app, @HighGamma100300HzCheckBoxValueChanged, true);
            app.HighGamma100300HzCheckBox.Text = 'High Gamma (100-300 Hz)';
            app.Overlay2Label = uilabel(app.OptionsUIFigure);
            app.Overlay2Label.Text = 'Overlay 2';
            app.PhaseCheckBox_2 = uicheckbox(app.OptionsUIFigure);
            app.PhaseCheckBox_2.Text = 'Phase';
            app.EnvelopeCheckBox_2 = uicheckbox(app.OptionsUIFigure);
            app.EnvelopeCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @EnvelopeCheckBox_2ValueChanged, true);
            app.EnvelopeCheckBox_2.Text = 'Envelope';
            app.Delta054HzCheckBox_2 = uicheckbox(app.OptionsUIFigure);
            app.Delta054HzCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @Delta054HzCheckBox_2ValueChanged, true);
            app.Delta054HzCheckBox_2.Text = 'Delta (0.5-4 Hz)';
            app.Theta410HzCheckBox_2 = uicheckbox(app.OptionsUIFigure);
            app.Theta410HzCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @Theta410HzCheckBox_2ValueChanged, true);
            app.Theta410HzCheckBox_2.Text = 'Theta (4-10 Hz)';
            app.Spindle1016HzCheckBox_2 = uicheckbox(app.OptionsUIFigure);
            app.Spindle1016HzCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @Spindle1016HzCheckBox_2ValueChanged, true);
            app.Spindle1016HzCheckBox_2.Text = 'Spindle (10-16 Hz)';
            app.LowGamma30100HzCheckBox_2 = uicheckbox(app.OptionsUIFigure);
            app.LowGamma30100HzCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @LowGamma30100HzCheckBox_2ValueChanged, true);
            app.LowGamma30100HzCheckBox_2.Text = 'Low Gamma (30-100 Hz)';
            app.HighGamma100300HzCheckBox_2 = uicheckbox(app.OptionsUIFigure);
            app.HighGamma100300HzCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @HighGamma100300HzCheckBox_2ValueChanged, true);
            app.HighGamma100300HzCheckBox_2.Text = 'High Gamma (100-300 Hz)';
            app.SDThreshPlot2EditFieldLabel = uilabel(app.OptionsUIFigure);
            app.SDThreshPlot2EditFieldLabel.Text = 'SD Thresh Plot2';
            app.SDThreshPlot1EditField = uieditfield(app.OptionsUIFigure, 'numeric');
            app.PlotThreshButton = uibutton(app.OptionsUIFigure, 'push');
            app.PlotThreshButton.ButtonPushedFcn = createCallbackFcn(app, @PlotThreshButtonPushed, true);
            app.PlotThreshButton.Text = 'Plot Thresh';
            app.SDThreshPlot2EditField = uieditfield(app.OptionsUIFigure, 'numeric');
            app.PlotThreshButton_2 = uibutton(app.OptionsUIFigure, 'push');
            app.PlotThreshButton_2.ButtonPushedFcn = createCallbackFcn(app, @PlotThreshButton_2Pushed, true);
            app.PlotThreshButton_2.Text = 'Plot Thresh';
            app.SpikeDynDirEditFieldLabel = uilabel(app.OptionsUIFigure);
            app.SpikeDynDirEditFieldLabel.Text = 'Spike Dyn Dir';
            app.FIDEditFieldLabel = uilabel(app.OptionsUIFigure);
            app.FIDEditFieldLabel.Text = 'FID';
            app.RowEditFieldLabel = uilabel(app.OptionsUIFigure);
            app.RowEditFieldLabel.Text = 'Row';
            app.ChannelNameEditFieldLabel = uilabel(app.OptionsUIFigure);
            app.ChannelNameEditFieldLabel.Text = 'Channel Name';
            app.FeedForwardYNDropDownLabel = uilabel(app.OptionsUIFigure);
            app.FeedForwardYNDropDownLabel.Text = 'Feed Forward? Y/N';
            app.LoadSpikeDynamics1Label = uilabel(app.OptionsUIFigure);
            app.LoadSpikeDynamics1Label.Text = 'Load Spike Dynamics 1';
            app.SpikeDynDirEditField = uieditfield(app.OptionsUIFigure, 'text');
            app.BurstBoundsLabel = uilabel(app.OptionsUIFigure);
            app.BurstBoundsLabel.Text = 'Burst Bounds';
            app.FIDEditField = uieditfield(app.OptionsUIFigure, 'text');
            app.ChannelNameEditField = uieditfield(app.OptionsUIFigure, 'text');
            app.ORLabel = uilabel(app.OptionsUIFigure);
            app.ORLabel.Text = 'OR';
            app.RowEditField = uieditfield(app.OptionsUIFigure, 'text');
            app.FeedForwardYNDropDown = uidropdown(app.OptionsUIFigure);
            app.FeedForwardYNDropDown.Items = {'N/A', 'Yes', 'No'};
            app.FeedForwardYNDropDown.Value = 'N/A';
            app.PlotButton_3 = uibutton(app.OptionsUIFigure, 'push');
            app.PlotButton_3.ButtonPushedFcn = createCallbackFcn(app, @PlotButton_3Pushed, true);
            app.PlotButton_3.Text = 'Plot';
            app.FeedForwardYNDropDown_2Label = uilabel(app.OptionsUIFigure);
            app.FeedForwardYNDropDown_2Label.Text = 'Feed Forward? Y/N';
            app.LoadSpikeDynamics2Label = uilabel(app.OptionsUIFigure);
            app.LoadSpikeDynamics2Label.Text = 'Load Spike Dynamics 2';
            app.SpikeDynDirEditField_2 = uieditfield(app.OptionsUIFigure, 'text');
            app.BurstBoundsLabel_2 = uilabel(app.OptionsUIFigure);
            app.BurstBoundsLabel_2.Text = 'Burst Bounds';
            app.FIDEditField_2 = uieditfield(app.OptionsUIFigure, 'text');
            app.ChannelNameEditField_2 = uieditfield(app.OptionsUIFigure, 'text');
            app.ChannelNameEditField_2Label = uilabel(app.OptionsUIFigure);
            app.ChannelNameEditField_2Label.Text = 'Channel Name';
            app.ORLabel_2 = uilabel(app.OptionsUIFigure);
            app.ORLabel_2.Text = 'OR';
            app.RowEditField_2 = uieditfield(app.OptionsUIFigure, 'text');
            app.RowEditField_2Label = uilabel(app.OptionsUIFigure);
            app.RowEditField_2Label.Text = 'Row';
            app.FeedForwardYNDropDown_2 = uidropdown(app.OptionsUIFigure);
            app.FeedForwardYNDropDown_2.Items = {'N/A', 'Yes', 'No'};
            app.FeedForwardYNDropDown_2.Value = 'N/A';
            app.PlotButton_4 = uibutton(app.OptionsUIFigure, 'push');
            app.PlotButton_4.ButtonPushedFcn = createCallbackFcn(app, @PlotButton_4Pushed, true);
            app.PlotButton_4.Text = 'Plot';
            app.FIDEditField_2Label = uilabel(app.OptionsUIFigure);
            app.FIDEditField_2Label.Text = 'FID';
            app.SpikeDynDirEditField_2Label = uilabel(app.OptionsUIFigure);
            app.SpikeDynDirEditField_2Label.Text = 'Spike Dyn Dir';
            app.SDThreshPlot1EditFieldLabel = uilabel(app.OptionsUIFigure);
            app.SDThreshPlot1EditFieldLabel.Text = 'SD Thresh Plot1';
            app.StopsEditFieldLabel = uilabel(app.OptionsUIFigure);
            app.StopsEditFieldLabel.Text = 'Stop (s)';
            app.StartsEditFieldLabel = uilabel(app.OptionsUIFigure);
            app.StartsEditFieldLabel.Text = 'Start (s)';
            app.Defaultfiltering3003000HzforspikesLabel_2 = uilabel(app.OptionsUIFigure);
            app.Defaultfiltering3003000HzforspikesLabel_2.Text = 'Default filtering: 300-3000 Hz for spikes';
            app.SpikeDirEditField_2Label = uilabel(app.OptionsUIFigure);
            app.SpikeDirEditField_2Label.Text = 'Spike Dir';
            app.RawDirEditField_2Label = uilabel(app.OptionsUIFigure);
            app.RawDirEditField_2Label.Text = 'Raw Dir';
            app.Plot2Label = uilabel(app.OptionsUIFigure);
            app.Plot2Label.Text = 'Plot 2';
            app.HighPassHzEditField_2Label = uilabel(app.OptionsUIFigure);
            app.HighPassHzEditField_2Label.Text = 'High Pass (Hz)';
            app.Defaultfiltering3003000HzforspikesLabel = uilabel(app.OptionsUIFigure);
            app.Defaultfiltering3003000HzforspikesLabel.Text = 'Default filtering: 300-3000 Hz for spikes';
            app.LowPassHzEditField_2Label = uilabel(app.OptionsUIFigure);
            app.LowPassHzEditField_2Label.Text = 'Low Pass (Hz)';
            app.SpikeDirEditFieldLabel = uilabel(app.OptionsUIFigure);
            app.SpikeDirEditFieldLabel.Text = 'Spike Dir';
            app.RawDirEditFieldLabel = uilabel(app.OptionsUIFigure);
            app.RawDirEditFieldLabel.Text = 'Raw Dir';
            app.Plot1Label = uilabel(app.OptionsUIFigure);
            app.Plot1Label.Text = 'Plot 1';
            app.PlotSpectrogramCheckBox = uicheckbox(app.OptionsUIFigure);
            app.PlotSpectrogramCheckBox.Text = 'Plot Spectrogram';
            app.PlotSpectrogramCheckBox_2 = uicheckbox(app.OptionsUIFigure);
            app.PlotSpectrogramCheckBox_2.Text = 'Plot Spectrogram';
            app.MaxHzEditFieldLabel = uilabel(app.OptionsUIFigure);
            app.MaxHzEditFieldLabel.Text = 'Max Hz';
            app.MaxHzEditField = uieditfield(app.OptionsUIFigure, 'numeric');
            app.MinHzEditFieldLabel = uilabel(app.OptionsUIFigure);
            app.MinHzEditFieldLabel.Text = 'Min Hz';
            app.MinHzEditField = uieditfield(app.OptionsUIFigure, 'numeric');
            app.MaxMagEditFieldLabel = uilabel(app.OptionsUIFigure);
            app.MaxMagEditFieldLabel.Text = 'Max Mag';
            app.MaxMagEditField = uieditfield(app.OptionsUIFigure, 'numeric');
            app.MinMagEditFieldLabel = uilabel(app.OptionsUIFigure);
            app.MinMagEditFieldLabel.Text = 'Min Mag';
            app.MinMagEditField = uieditfield(app.OptionsUIFigure, 'numeric');
            % Show the figure after all components are created
            app.OptionsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PlotTwoSigsOptions_240517(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.OptionsUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.OptionsUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.OptionsUIFigure)
        end
    end
end