classdef PlotTwoSigs_240517 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PlottingUIFigure  matlab.ui.Figure
        FileMenu          matlab.ui.container.Menu
        SaveAsMenu        matlab.ui.container.Menu
        EditMenu          matlab.ui.container.Menu
        CopyFigureMenu    matlab.ui.container.Menu
        OptionsButton     matlab.ui.control.Button
        UIAxes            matlab.ui.control.UIAxes
        UIAxes2           matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        DialogApp % Reference to app
        BurstApp
        plot1_raw
        plot2_raw
        plot1_filt
        plot2_filt
    end
    
    methods (Access = public)

        function plot_dir(app,myAxis,dir,lowpass_filter,highpass_filter,fs, start, stop, ifSpec, maxHz, minHz, maxMag, minMag)

            dir=erase(dir,{'"'});

            % dir=app.MatDirEditField.Value;
            data=load(dir);
            data=data.data;
            mychannel=split(dir,{'\','/','.'});
            mychannel=mychannel(end-1);
            % fs=app.SamplingratekHzEditField.Value*1000;
            fs=fs*1000;
            t=0:1/fs:length(data)/fs-(1/fs);

            %low pass filter
            if isnan(lowpass_filter)
                filtdata=data;
            else
                [A,B,C,D]=butter(8,lowpass_filter/(fs/2),'low');
                [sos,g]=ss2sos(A,B,C,D);
                filtdata=filtfilt(sos,g,data);
            end

            %low pass filter
            if isnan(highpass_filter)
                filtdata=filtdata;
            else
                [A,B,C,D]=butter(8,highpass_filter/(fs/2),'high');
                [sos,g]=ss2sos(A,B,C,D);
                filtdata=filtfilt(sos,g,filtdata);
            end

            if myAxis==1
                clf(app.UIAxes)
                cla(app.UIAxes,"reset")
                app.plot1_filt=filtdata;
                app.plot1_raw=data;
                if ~ifSpec
                    plot(app.UIAxes,t,filtdata,'k',"LineWidth",1)
                    ylabel(app.UIAxes,"\muV")
                    ylim(app.UIAxes,"auto")
                else
                    re_fs=1000;
                    % filtdata=resample(filtdata,re_fs,fs);
                    fb=cwtfilterbank("SignalLength",length(resample(filtdata,re_fs,fs)),"Wavelet","amor","SamplingFrequency",re_fs,"FrequencyLimits",[minHz,maxHz]);
                    % [cfs,frq]=cwt(resample(filtdata,re_fs,fs),"morse",fs,"FrequencyLimits",[0.5,300],"FilterBank",fb);
                    [cfs,frq]=cwt(resample(filtdata,re_fs,fs),FilterBank=fb);
                    % cfs(abs(cfs)>maxMag)=maxMag;
                    % cfs(abs(cfs)<minMag)=minMag;
                    surface(app.UIAxes,1/re_fs:1/re_fs:length(filtdata)/fs,frq,(log10(abs(cfs)./frq)))
                    shading(app.UIAxes,"flat")
                    yticks(app.UIAxes,[3,10,30,100,300])
                    app.UIAxes.YScale="log";
                    ylabel(app.UIAxes,"Hz")
                    c=colorbar(app.UIAxes);
                    colormap(app.UIAxes,flipud(slanCM("plasma")))
                    c.Label.String="Log Magnitude/Hz";
                    ylim(app.UIAxes,[minHz,maxHz])
                    clim(app.UIAxes,[log10(minMag),log10(maxMag)])
                end
                % ytickangle(app.UIAxes,90)
                xlim(app.UIAxes,[start,stop])
                title(app.UIAxes,mychannel,'FontSize',18)
                set(app.UIAxes,'FontSize',36)
                xlabel(app.UIAxes,"Seconds")
            elseif myAxis==2
                clf(app.UIAxes2)
                cla(app.UIAxes2,"reset")
                app.plot2_filt=filtdata;
                app.plot2_raw=data;
                if ~ifSpec
                    plot(app.UIAxes2,t,filtdata,'k',"LineWidth",1)
                    ylabel(app.UIAxes2,"\muV")
                else
                    re_fs=1000;
                    % filtdata=resample(filtdata,re_fs,fs);
                    [cfs,frq]=cwt(resample(filtdata,re_fs,fs),"morse",fs,"FrequencyLimits",[0.5,300]);
                    surface(app.UIAxes2,1/re_fs:1/re_fs:length(filtdata)/fs,frq,abs(cfs))
                    shading(app.UIAxes2,"flat")
                    yticks(app.UIAxes2,[0.5,4,10,30,100,300])
                    app.UIAxes2.YScale="log";
                    ylabel(app.UIAxes2,"Hz")
                    c=colorbar(app.UIAxes2);
                    colormap(app.UIAxes2,slanCM("magma"))
                    c.Label.String="Magnitude";
                    ylim(app.UIAxes2,[minHz,maxHz])
                    clim(app.UIAxes2,[log10(minMag),log10(maxMag)])
                end
                % ytickangle(app.UIAxes2,90)
                xlim(app.UIAxes2,[start,stop])
                title(app.UIAxes2,mychannel,'FontSize',18)
                set(app.UIAxes2,'FontSize',36)
                ylim(app.UIAxes2,"auto")
                
                xlabel(app.UIAxes2,"Seconds")
            end
        end
        
        function updateplot(app)
            
        end
        
        function plot_spikes(app,myAxis,dir,start,stop,existsLFP)

            dir=erase(dir,{'"'});
            
            if contains(dir,"times")
                spikes=load(dir);
                spikes=spikes.cluster_class;
                spikes=spikes(:,2)/1000;
                myChannel=split(dir,{'/','\','.','_'});
                myChannel=myChannel(end-1);
            else
                spikes=load(dir);
                spikes=spikes.index/1000; %ms to s
                myChannel=split(dir,{'/','\','.','_'});
                myChannel=myChannel(end-2);
            end
            
            if size(spikes,2)>size(spikes,1)
                spikes=spikes';
            end

            spikes=[spikes,spikes];

            if myAxis==1
                height=[max(ylim(app.UIAxes))*0.9,max(ylim(app.UIAxes))];
                if existsLFP
                    hold(app.UIAxes,'on')
                end
                plot(app.UIAxes,spikes',repmat(height,size(spikes,1),1)','Color',[1,0,0,0.5],'LineStyle','-','Marker','none')
                xlim(app.UIAxes,[start,stop])
                hold(app.UIAxes,'off')
                title(app.UIAxes,myChannel,'FontSize',18)
                set(app.UIAxes,'FontSize',36)
            elseif myAxis==2
                height=[max(ylim(app.UIAxes2))*0.9,max(ylim(app.UIAxes2))];
                if existsLFP
                    hold(app.UIAxes2,'on')
                end
                plot(app.UIAxes2,spikes',repmat(height,size(spikes,1),1)','Color',[1,0,0,0.5],'LineStyle','-','Marker','none')
                xlim(app.UIAxes2,[start,stop])
                hold(app.UIAxes2,'off')
                title(app.UIAxes2,myChannel,'FontSize',18)
                set(app.UIAxes2,'FontSize',36)
            end
            
            
        end
        
        function plotPhase(app, value, myAxis, dir, fs,lowpass_filter,highpass_filter)

            dir=erase(dir,{'"'});

            if value
                % dir=app.MatDirEditField.Value;
                data=load(dir);
                data=data.data;
                % fs=app.SamplingratekHzEditField.Value*1000;
                fs=fs*1000;
                t=0:1/fs:length(data)/fs-(1/fs);

                %low pass filter
                if isnan(lowpass_filter)
                    filtdata=data;
                else
                    [A,B,C,D]=butter(8,lowpass_filter/(fs/2),'low');
                    [sos,g]=ss2sos(A,B,C,D);
                    filtdata=filtfilt(sos,g,data);
                end
    
                %low pass filter
                if isnan(highpass_filter)
                    filtdata=filtdata;
                else
                    [A,B,C,D]=butter(8,highpass_filter/(fs/2),'high');
                    [sos,g]=ss2sos(A,B,C,D);
                    filtdata=filtfilt(sos,g,filtdata);
                end

                hilbertData=hilbert(filtdata);
                angleData=angle(hilbertData);
                angleData=rad2deg(wrapTo2Pi(angleData));
                % hold(myAxis,"on")
                yyaxis(myAxis,"right")
                % myAxis.YAxis(1).Color='k';
                myAxis.YAxis(2).Color='m';
                plot(myAxis,t,angleData,'Color',"m","LineStyle","-");
                ylabel(myAxis,"Phase (radians)")
                ylim(myAxis,[min(angleData)-(0.2*abs(min(angleData))),3*(max(angleData)+(0.1*abs(max(angleData))))])
                % hold(myAxis,"off")
                % yticks(myAxis,[-pi,-pi/2,0,pi/2,pi])
                % yticklabels(myAxis,{'-\pi','-\pi/2','0','\pi/2','\pi'})
                yticks(myAxis,[0:90:360])
                yyaxis(myAxis,"left")
                % app.UIAxes.Position
            else
                if exist(app.phase1)
                    % delete(app.phase1)
                end
            end
        end
        
        function results = plotEnvelope(app, value, myAxis, dir, fs, lowpass_filter, highpass_filter)

            dir=erase(dir,{'"'});

            if value
                % dir=app.MatDirEditField.Value;
                data=load(dir);
                data=data.data;
                % fs=app.SamplingratekHzEditField.Value*1000;
                fs=fs*1000;
                t=0:1/fs:length(data)/fs-(1/fs);

                %low pass filter
                if isnan(lowpass_filter)
                    filtdata=data;
                else
                    [A,B,C,D]=butter(8,lowpass_filter/(fs/2),'low');
                    [sos,g]=ss2sos(A,B,C,D);
                    filtdata=filtfilt(sos,g,data);
                end
    
                %low pass filter
                if isnan(highpass_filter)
                    filtdata=filtdata;
                else
                    [A,B,C,D]=butter(8,highpass_filter/(fs/2),'high');
                    [sos,g]=ss2sos(A,B,C,D);
                    filtdata=filtfilt(sos,g,filtdata);
                end

                hilbertData=hilbert(filtdata);
                ampData=abs(hilbertData);
                % ampData=envelope(filtdata,highpass_filter/(fs/2),'analytic');
                hold(myAxis,"on")
                amp1=plot(myAxis,t,ampData,"LineWidth",1,"Color","g");
                hold(myAxis,"off")
            else
                if exist(app.phase1)
                    % delete(app.phase1)
                end
            end
        end
        
        function plotBurstBounds(app, myAxis, dir, fs, row)

            dir=erase(dir,{'"'});
            
            spike_dyn_table=importdata(dir);
            fs=fs*1000;
            % if nargin==5
            %     row=varargin{1};
            % elseif nargin==6
            %     row=find(spike_dyn_table.fi==varargin{1} & spike_dyn_table.channel_name==varargin{2});
            % elseif nargin==7 
            %     if varargin{3}=="Yes"
            %         row=find(spike_dyn_table.fi==varargin{1} & spike_dyn_table.channel_name==varargin{2} & spike_dyn_table.if_ff==1);
            %     else
            %         row=find(spike_dyn_table.fi==varargin{1} & spike_dyn_table.channel_name==varargin{2} & spike_dyn_table.if_ff==0);
            %     end
            % else
            %     return
            % end

            BurstBounds=spike_dyn_table.BurstBounds{row}/fs;

            if myAxis==1
                height=ylim(app.UIAxes);
                hold(app.UIAxes,'on')
                for nBursts=1:length(BurstBounds)
                    % r=rectangle(app.UIAxes,"Position",[spike_dyn_table.BurstBounds{nBursts}(1),height(1),...
                    %     spike_dyn_table.BurstBounds{nBursts}(2)-spike_dyn_table.BurstBounds{nBursts}(1),...
                    %     ylim(2)-ylim(1)]);
                    % r.EdgeColor=""
                    x=[BurstBounds(nBursts,1), BurstBounds(nBursts,2),...
                        BurstBounds(nBursts,2), BurstBounds(nBursts,1)];
                    y=[height(1),height(1),height(2),height(2)];
                    v=[x',y'];
                    f=[1,2,3,4];
                    patch(app.UIAxes,'Faces',f,'Vertices',v,'FaceColor','y','FaceAlpha',0.5,'EdgeColor','none')
                end
                hold(app.UIAxes,'off')
            elseif myAxis==2
                height=ylim(app.UIAxes2);
                hold(app.UIAxes2,'on')
                for nBursts=1:length(BurstBounds)
                    % r=rectangle(app.UIAxes,"Position",[spike_dyn_table.BurstBounds{nBursts}(1),height(1),...
                    %     spike_dyn_table.BurstBounds{nBursts}(2)-spike_dyn_table.BurstBounds{nBursts}(1),...
                    %     ylim(2)-ylim(1)]);
                    % r.EdgeColor=""
                    x=[BurstBounds(nBursts,1), BurstBounds(nBursts,2),...
                        BurstBounds(nBursts,2), BurstBounds(nBursts,1)];
                    y=[height(1),height(1),height(2),height(2)];
                    v=[x',y'];
                    f=[1,2,3,4];
                    patch(app.UIAxes2,'Faces',f,'Vertices',v,'FaceColor','y','FaceAlpha',0.5,'EdgeColor','none')
                end
                hold(app.UIAxes2,'off')
            end

        end
        
        function plotThresh(app,thresh,myAxis)
            if myAxis==1
                thresh1=std(app.plot1_filt)*thresh;
                yline(app.UIAxes,-thresh1,'r')
            end

            if myAxis==2
                thresh2=std(app.plot2_filt)*thresh;
                yline(app.UIAxes2,-thresh2,'r')
            end
        end

        function plotDeltaOverlay(app,myAxis,myDir,fs,boxState)
            data=load(erase(myDir,{'"'}));
            data=data.data;
            fs=fs*1000;
            t=0:1/fs:length(data)/fs-(1/fs);

            if boxState & isempty(findobj(myAxis,'Color',[0,1,1]))
                [A,B,C,D]=butter(8,4/(fs/2),'low');
                [sos,g]=ss2sos(A,B,C,D);
                filtdata=filtfilt(sos,g,data);

                [A,B,C,D]=butter(8,0.5/(fs/2),'high');
                [sos,g]=ss2sos(A,B,C,D);
                filtdata=filtfilt(sos,g,filtdata);
                hold(myAxis,"on")
                plot(myAxis,t,filtdata,'Color',[0,1,1])
                hold(myAxis,"off")
            elseif ~boxState & ~isempty(findobj(myAxis,'Color',[0,1,1]))
                delete(findobj(myAxis,'Color',[0,1,1]))
            end
        end

        function plotThetaOverlay(app,myAxis,myDir,fs,boxState)
            data=load(erase(myDir,{'"'}));
            data=data.data;
            fs=fs*1000;
            t=0:1/fs:length(data)/fs-(1/fs);

            if boxState & isempty(findobj(myAxis,'Color',[0.5,1,0]))
                [A,B,C,D]=butter(8,10/(fs/2),'low');
                [sos,g]=ss2sos(A,B,C,D);
                filtdata=filtfilt(sos,g,data);

                [A,B,C,D]=butter(8,4/(fs/2),'high');
                [sos,g]=ss2sos(A,B,C,D);
                filtdata=filtfilt(sos,g,filtdata);
                hold(myAxis,"on")
                plot(myAxis,t,filtdata,'Color',[0.5,1,0])
                hold(myAxis,"off")
            elseif ~boxState & ~isempty(findobj(myAxis,'Color',[0.5,1,0]))
                delete(findobj(myAxis,'Color',[0.5,1,0]))
            end

        end

        function plotSpindleOverlay(app,myAxis,myDir,fs,boxState)
            data=load(erase(myDir,{'"'}));
            data=data.data;
            fs=fs*1000;
            t=0:1/fs:length(data)/fs-(1/fs);

            if boxState & isempty(findobj(myAxis,'Color',[1,0,1]))
                [A,B,C,D]=butter(8,16/(fs/2),'low');
                [sos,g]=ss2sos(A,B,C,D);
                filtdata=filtfilt(sos,g,data);

                [A,B,C,D]=butter(8,10/(fs/2),'high');
                [sos,g]=ss2sos(A,B,C,D);
                filtdata=filtfilt(sos,g,filtdata);
                hold(myAxis,"on")
                plot(myAxis,t,filtdata,'Color',[1,0,1])
                hold(myAxis,"off")
            elseif ~boxState & ~isempty(findobj(myAxis,'Color',[1,0,1]))
                delete(findobj(myAxis,'Color',[1,0,1]))
            end

        end

        function plotLowGammaOverlay(app,myAxis,myDir,fs,boxState)
            data=load(erase(myDir,{'"'}));
            data=data.data;
            fs=fs*1000;
            t=0:1/fs:length(data)/fs-(1/fs);

            if boxState & isempty(findobj(myAxis,'Color',[1,1,0]))
                [A,B,C,D]=butter(8,100/(fs/2),'low');
                [sos,g]=ss2sos(A,B,C,D);
                filtdata=filtfilt(sos,g,data);

                [A,B,C,D]=butter(8,30/(fs/2),'high');
                [sos,g]=ss2sos(A,B,C,D);
                filtdata=filtfilt(sos,g,filtdata);
                hold(myAxis,"on")
                plot(myAxis,t,filtdata,'Color',[1,1,0])
                hold(myAxis,"off")
            elseif ~boxState & ~isempty(findobj(myAxis,'Color',[1,1,0]))
                delete(findobj(myAxis,'Color',[1,1,0]))
            end

        end

        function plotHighGammaOverlay(app,myAxis,myDir,fs,boxState)
            data=load(erase(myDir,{'"'}));
            data=data.data;
            fs=fs*1000;
            t=0:1/fs:length(data)/fs-(1/fs);

            if boxState & isempty(findobj(myAxis,'Color',[1,0.5,0]))
                [A,B,C,D]=butter(8,300/(fs/2),'low');
                [sos,g]=ss2sos(A,B,C,D);
                filtdata=filtfilt(sos,g,data);

                [A,B,C,D]=butter(8,100/(fs/2),'high');
                [sos,g]=ss2sos(A,B,C,D);
                filtdata=filtfilt(sos,g,filtdata);
                hold(myAxis,"on")
                plot(myAxis,t,filtdata,'Color',[1,0.5,0])
                hold(myAxis,"off")
            elseif ~boxState & ~isempty(findobj(myAxis,'Color',[1,0.5,0]))
                delete(findobj(myAxis,'Color',[1,0.5,0]))
            end

        end
    end
    
    methods (Access = private)

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: OptionsButton
        function OptionsButtonPushed(app, event)
            linkaxes([app.UIAxes,app.UIAxes2],'x')
            app.DialogApp=PlotTwoSigsOptions_240517(app);

            app.OptionsButton.Enable='off';
        end

        % Close request function: PlottingUIFigure
        function PlottingUIFigureCloseRequest(app, event)
            delete(app.DialogApp)
            delete(app)
            if isvalid(app.BurstApp)
                delete(app.BurstApp)
            end
            
        end

        % Menu selected function: SaveAsMenu
        function SaveAsMenuSelected(app, event)
            [filename, pathname]=uiputfile('*.fig;*.png');
            exportgraphics(app.PlottingUIFigure,fullfile(pathname,filename))
        end

        % Menu selected function: CopyFigureMenu
        function CopyFigureMenuSelected(app, event)
            copygraphics(app.PlottingUIFigure)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PlottingUIFigure = uifigure('Visible', 'off');
            app.PlottingUIFigure.Name = 'Plotting';
            app.PlottingUIFigure.CloseRequestFcn = createCallbackFcn(app, @PlottingUIFigureCloseRequest, true);
            app.FileMenu = uimenu(app.PlottingUIFigure);
            app.FileMenu.Text = 'File';
            app.SaveAsMenu = uimenu(app.FileMenu);
            app.SaveAsMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveAsMenuSelected, true);
            app.SaveAsMenu.Text = 'Save As';
            app.EditMenu = uimenu(app.PlottingUIFigure);
            app.EditMenu.Text = 'Edit';
            app.CopyFigureMenu = uimenu(app.EditMenu);
            app.CopyFigureMenu.MenuSelectedFcn = createCallbackFcn(app, @CopyFigureMenuSelected, true);
            app.CopyFigureMenu.Text = 'Copy Figure';
            app.UIAxes2 = uiaxes(app.PlottingUIFigure);
            title(app.UIAxes2, 'Plot 2')
            xlabel(app.UIAxes2, 'Seconds')
            ylabel(app.UIAxes2, '\muV')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes = uiaxes(app.PlottingUIFigure);
            title(app.UIAxes, 'Plot 1')
            xlabel(app.UIAxes, 'Seconds')
            ylabel(app.UIAxes, '\muV')
            zlabel(app.UIAxes, 'Z')
            app.OptionsButton = uibutton(app.PlottingUIFigure, 'push');
            app.OptionsButton.ButtonPushedFcn = createCallbackFcn(app, @OptionsButtonPushed, true);
            app.OptionsButton.Text = 'Options';
            % Show the figure after all components are created
            app.PlottingUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PlotTwoSigs_240517

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.PlottingUIFigure)
            else

                % Focus the running singleton app
                figure(runningApp.PlottingUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PlottingUIFigure)
        end
    end
end